classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        RandomizeButton              matlab.ui.control.Button
        StartStopButton              matlab.ui.control.Button
        Lamp                         matlab.ui.control.Lamp
        AppliedFieldDirectionMagnitudeSliderLabel  matlab.ui.control.Label
        AppliedFieldDirectionMagnitudeSlider  matlab.ui.control.Slider
        ofVariantsSpinnerLabel       matlab.ui.control.Label
        ofVariantsSpinner            matlab.ui.control.Spinner
        TemperatureSlider            matlab.ui.control.Slider
        NeighborCouplingSliderLabel  matlab.ui.control.Label
        NeighborCouplingSlider       matlab.ui.control.Slider
        PlotStepsLabel               matlab.ui.control.Label
        PlotStepsSpinner             matlab.ui.control.Spinner
        Knob                         matlab.ui.control.Knob
        Temperature10Label           matlab.ui.control.Label
        h_thetaLabel                 matlab.ui.control.Label
        h_magLabel                   matlab.ui.control.Label
    end


    properties (Access = private)
        Model   PottsModel
        Running logical
        J       double
        h       double
        h_theta double
        N       double
        alpha   double
        P_steps double
    end

    methods (Access = private)
    
        function Start(app)
            app.Running = true;
            app.Lamp.Color = 'green';
            count = 0;
            while app.Running
                app.Model.MC_step(app.N,app.J,app.h,app.h_theta,app.alpha);
                count = count + 1;
                pause(0.000001);
                if ~isvalid(app)
                    return;
                end
                if count > app.P_steps
                    app.Model.plot();
                    drawnow();
                    count = 0;
                end
                
            end
        end
        
        function Stop(app)
            app.Running = false;
            app.Lamp.Color = 'red';
            fprintf('Stop\n');
            drawnow();
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function Begin(app)
            app.N = app.ofVariantsSpinner.Value;
            app.Model = PottsModel(app.UIAxes,100,100,app.N);
            app.Model.plot();
            app.J = app.NeighborCouplingSlider.Value;
            app.h = app.AppliedFieldDirectionMagnitudeSlider.Value;
            app.h_theta = app.Knob.Value;
            app.alpha = 10^(-app.TemperatureSlider.Value);
            app.P_steps = app.PlotStepsSpinner.Value;
            app.h_thetaLabel.Text = sprintf('%0.1f [deg]',app.Knob.Value);
            app.h_magLabel.Text = sprintf('%0.1f',app.AppliedFieldDirectionMagnitudeSlider);
            app.Temperature10Label.Text = sprintf('Temperature: %0.3e',10^(app.TemperatureSlider.Value));
        end

        % Button pushed function: RandomizeButton
        function Randomize(app, event)
            app.Model.randomize(app.N);
            app.Model.plot();
        end

        % Callback function
        function Update(app, event)
            app.Model.plot();
        end

        % Button pushed function: StartStopButton
        function StartStop(app, event)
            if app.Running
                app.Stop();
            else
                app.Start();
            end
        end

        % Callback function: Knob
        function set_theta_h(app, event)
           value = app.Knob.Value;
            app.h_theta = value*pi/180;
            app.h_thetaLabel.Text = sprintf('%0.1f [deg]',value);
        end

        % Callback function: AppliedFieldDirectionMagnitudeSlider
        function set_h(app, event)
            value = app.AppliedFieldDirectionMagnitudeSlider.Value;
            app.h = value;
            app.h_magLabel.Text = sprintf('%f',value);
        end

        % Callback function: TemperatureSlider
        function set_Temp(app, event)
            logTemp = app.TemperatureSlider.Value;
            app.alpha = 10^(-logTemp);
            app.Temperature10Label.Text = sprintf('Temperature: %0.3e',10^(logTemp));
        end

        % Callback function: NeighborCouplingSlider
        function set_J(app, event)
            value = app.NeighborCouplingSlider.Value;
            app.J = value;
        end

        % Value changed function: ofVariantsSpinner
        function set_N(app, event)
            value = app.ofVariantsSpinner.Value;
            app.N = value;
        end

        % Value changed function: PlotStepsSpinner
        function set_plot_steps(app, event)
            value = app.PlotStepsSpinner.Value;
            app.P_steps = value;
        end

        % Callback function
        function set_th(app, event)

        end

        % Close request function: UIFigure
        function Exit(app, event)
            delete(app.UIAxes)
            delete(app)
            return;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @Exit, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = {'[ ]'};
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = {'[ ]'};
            app.RandomizeButton = uibutton(app.UIFigure, 'push');
            app.RandomizeButton.ButtonPushedFcn = createCallbackFcn(app, @Randomize, true);
            app.RandomizeButton.Text = 'Randomize';
            app.StartStopButton = uibutton(app.UIFigure, 'push');
            app.StartStopButton.ButtonPushedFcn = createCallbackFcn(app, @StartStop, true);
            app.StartStopButton.Text = 'Start/Stop';
            app.Lamp = uilamp(app.UIFigure);
            app.AppliedFieldDirectionMagnitudeSliderLabel = uilabel(app.UIFigure);
            app.AppliedFieldDirectionMagnitudeSliderLabel.Text = 'Applied Field Direction & Magnitude';
            app.AppliedFieldDirectionMagnitudeSlider = uislider(app.UIFigure);
            app.AppliedFieldDirectionMagnitudeSlider.Limits = [0 25];
            app.AppliedFieldDirectionMagnitudeSlider.ValueChangedFcn = createCallbackFcn(app, @set_h, true);
            app.AppliedFieldDirectionMagnitudeSlider.ValueChangingFcn = createCallbackFcn(app, @set_h, true);
            app.ofVariantsSpinnerLabel = uilabel(app.UIFigure);
            app.ofVariantsSpinnerLabel.Text = '# of Variants';
            app.ofVariantsSpinner = uispinner(app.UIFigure);
            app.ofVariantsSpinner.Limits = [1 32];
            app.ofVariantsSpinner.ValueChangedFcn = createCallbackFcn(app, @set_N, true);
            app.ofVariantsSpinner.Value = 8;
            app.TemperatureSlider = uislider(app.UIFigure);
            app.TemperatureSlider.Limits = [-5 5];
            app.TemperatureSlider.ValueChangedFcn = createCallbackFcn(app, @set_Temp, true);
            app.TemperatureSlider.ValueChangingFcn = createCallbackFcn(app, @set_Temp, true);
            app.NeighborCouplingSliderLabel = uilabel(app.UIFigure);
            app.NeighborCouplingSliderLabel.Text = 'Neighbor Coupling';
            app.NeighborCouplingSlider = uislider(app.UIFigure);
            app.NeighborCouplingSlider.Limits = [0 25];
            app.NeighborCouplingSlider.ValueChangedFcn = createCallbackFcn(app, @set_J, true);
            app.NeighborCouplingSlider.ValueChangingFcn = createCallbackFcn(app, @set_J, true);
            app.NeighborCouplingSlider.Value = 1;
            app.PlotStepsLabel = uilabel(app.UIFigure);
            app.PlotStepsLabel.Text = '# Plot Steps';
            app.PlotStepsSpinner = uispinner(app.UIFigure);
            app.PlotStepsSpinner.Limits = [1 Inf];
            app.PlotStepsSpinner.ValueChangedFcn = createCallbackFcn(app, @set_plot_steps, true);
            app.PlotStepsSpinner.Value = 1;
            app.Knob = uiknob(app.UIFigure, 'continuous');
            app.Knob.Limits = [-180 180];
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @set_theta_h, true);
            app.Knob.ValueChangingFcn = createCallbackFcn(app, @set_theta_h, true);
            app.Temperature10Label = uilabel(app.UIFigure);
            app.Temperature10Label.Text = 'Temperature: 1.0';
            app.h_thetaLabel = uilabel(app.UIFigure);
            app.h_thetaLabel.Text = 'h_theta';
            app.h_magLabel = uilabel(app.UIFigure);
            app.h_magLabel.Text = 'h_mag';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @Begin)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end